classdef DoAnCuoiKi_TichPhanApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NhaphamEditFieldLabel      matlab.ui.control.Label
        NhaphamEditField           matlab.ui.control.EditField
        NhapcanAEditFieldLabel     matlab.ui.control.Label
        NhapcanAEditField          matlab.ui.control.EditField
        NhapcanBEditFieldLabel     matlab.ui.control.Label
        NhapcanBEditField          matlab.ui.control.EditField
        NhapNEditFieldLabel        matlab.ui.control.Label
        NhapNEditField             matlab.ui.control.EditField
        UIAxes                     matlab.ui.control.UIAxes
        RUNButton                  matlab.ui.control.StateButton
        KetquaLabel                matlab.ui.control.Label
        GIAODIENTINHTICHPHANLabel  matlab.ui.control.Label
        TichPhanHinhThangLabel     matlab.ui.control.Label
        TichPhanSimpsonLabel       matlab.ui.control.Label
        TichPhanChinhXacLabel      matlab.ui.control.Label
        TichphanhinhthangLabel     matlab.ui.control.Label
        TichphanSimpsonLabel       matlab.ui.control.Label
        TichphanchinhxacLabel      matlab.ui.control.Label
    end

%     

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RUNButton
        function RUNButtonValueChanged(app, event)
            syms x;            % Get input values
            fx = str2func(['@(x) ' app.NhaphamEditField.Value]);
            a = str2num(app.NhapcanAEditField.Value);
            b = str2num(app.NhapcanBEditField.Value);
            N = str2num(app.NhapNEditField.Value);
            
            tpHinhthang_result = tichphanhinhthang(fx, a, b, N);
            tpSimpson_result = tichphanSimpson(fx, a, b, N);
            tpChinhxac_result = tichphanchinhxac(fx, a, b);
            
            result_str=num2str(tpHinhthang_result,'%.10f');
            result_str1=num2str(tpSimpson_result,'%.10f');
            result_str2=num2str(tpChinhxac_result,'%.10f');
            app.TichPhanHinhThangLabel.Text = result_str;
            app.TichPhanSimpsonLabel.Text = result_str1;
            app.TichPhanChinhXacLabel.Text = result_str2;
            
            x = linspace(a, b, 1000);
            y = arrayfun(fx, x);
            plot(app.UIAxes, x, y);
            title(app.UIAxes, 'Do thi f(x) trong khoang tich phan');
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'f(x)');
            grid (app.UIAxes,"on")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NhaphamEditFieldLabel = uilabel(app.UIFigure);
            app.NhaphamEditFieldLabel.Text = 'Nhap ham';
            app.NhaphamEditField = uieditfield(app.UIFigure, 'text');
            app.NhapcanAEditFieldLabel = uilabel(app.UIFigure);
            app.NhapcanAEditFieldLabel.Text = 'Nhap can A';
            app.NhapcanAEditField = uieditfield(app.UIFigure, 'text');
            app.NhapcanBEditFieldLabel = uilabel(app.UIFigure);
            app.NhapcanBEditFieldLabel.Text = 'Nhap can B ';
            app.NhapcanBEditField = uieditfield(app.UIFigure, 'text');
            app.NhapNEditFieldLabel = uilabel(app.UIFigure);
            app.NhapNEditFieldLabel.Text = 'Nhap N';
            app.NhapNEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Do thi f(x) trong khoang tich phan')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'f(x)')
            app.RUNButton = uibutton(app.UIFigure, 'state');
            app.RUNButton.ValueChangedFcn = createCallbackFcn(app, @RUNButtonValueChanged, true);
            app.RUNButton.Icon = 'run.png';
            app.RUNButton.Text = 'RUN';
            app.KetquaLabel = uilabel(app.UIFigure);
            app.KetquaLabel.Text = 'Ket qua';
            app.GIAODIENTINHTICHPHANLabel = uilabel(app.UIFigure);
            app.GIAODIENTINHTICHPHANLabel.Text = 'GIAO DIEN TINH TICH PHAN';
            app.TichPhanHinhThangLabel = uilabel(app.UIFigure);
            app.TichPhanHinhThangLabel.Text = '';
            app.TichPhanSimpsonLabel = uilabel(app.UIFigure);
            app.TichPhanSimpsonLabel.Text = '';
            app.TichPhanChinhXacLabel = uilabel(app.UIFigure);
            app.TichPhanChinhXacLabel.Text = '';
            app.TichphanhinhthangLabel = uilabel(app.UIFigure);
            app.TichphanhinhthangLabel.Text = 'Tich phan hinh thang';
            app.TichphanSimpsonLabel = uilabel(app.UIFigure);
            app.TichphanSimpsonLabel.Text = 'Tich phan Simpson';
            app.TichphanchinhxacLabel = uilabel(app.UIFigure);
            app.TichphanchinhxacLabel.Text = 'Tich phan chinh xac';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAnCuoiKi_TichPhanApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end